classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ApplyButton           matlab.ui.control.Button
        DefectsDropDown       matlab.ui.control.DropDown
        DefectsDropDownLabel  matlab.ui.control.Label
        LoadImageButton       matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function defectFunctions = loadFunctions(app)
            defectFunctions.detectGlove = @detectGlove;
            defectFunctions.detectGloveType = @detectGloveType;
            defectFunctions.defecteDectionBrian = @defectDetectionBrian;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.jpg;*.jpeg;*.JPEG;'},"Open file");
            fullpathname = strcat(pathname,filename);
            setappdata(0,'pathname',fullpathname);
            image1 = imread(fullpathname);
            imshow(image1,'parent',app.UIAxes)
            figure(app.UIFigure);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            img = getappdata(0,'pathname');
            switch app.DefectsDropDown.Value
                case 'Holes'
                    detectHoles(img);
                case 'Torn'
                    detectTornGloves(img);
                case 'Stain'
                    detectStain(img);
                case 'Cut'
                    detectSingleCut(img);
                case 'Multiple cut'
                    detectMultipleCut(img);
                case 'No beading'
                    detectNoBeading(img);
                case 'No Finger'
                    defectDetection(img);
            end
        end

        % Value changed function: DefectsDropDown
        function DefectsDropDownValueChanged(app, event)
            value = app.DefectsDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DefectsDropDownLabel = uilabel(app.UIFigure);
            app.DefectsDropDownLabel.Text = 'Defects';
            app.DefectsDropDown = uidropdown(app.UIFigure);
            app.DefectsDropDown.Items = {'No Finger', 'Tear', 'Thinner', 'Stain', 'Holes', 'Torn'};
            app.DefectsDropDown.ValueChangedFcn = createCallbackFcn(app, @DefectsDropDownValueChanged, true);
            app.DefectsDropDown.Value = 'No Finger';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end